classdef FEMLabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FEMLabUIFigure                 matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SavesettingsMenu               matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        MesherTab                      matlab.ui.container.Tab
        CurrentMeshPanel               matlab.ui.container.Panel
        meshDisplayAxes                matlab.ui.control.UIAxes
        MesherPanel                    matlab.ui.container.Panel
        ElementtypeButtonGroup         matlab.ui.container.ButtonGroup
        Triangle1stOrderButton         matlab.ui.control.RadioButton
        Triangle2ndOrderButton         matlab.ui.control.RadioButton
        RunsetupButton                 matlab.ui.control.Button
        EditgeometryButton             matlab.ui.control.Button
        MeshInfoTable                  matlab.ui.control.Table
        StateLampLabel_2               matlab.ui.control.Label
        mesherStateLamp                matlab.ui.control.Lamp
        ProblemSetupTab                matlab.ui.container.Tab
        ProblemSetupPanel              matlab.ui.container.Panel
        RegionsPanel                   matlab.ui.container.Panel
        IDTable                        matlab.ui.control.Table
        NameTable                      matlab.ui.control.Table
        TypeTable                      matlab.ui.control.Table
        MatPropTable                   matlab.ui.control.Table
        SourPropTable                  matlab.ui.control.Table
        ProblemtypeButtonGroup         matlab.ui.container.ButtonGroup
        ElectrostaticButton            matlab.ui.control.RadioButton
        StationarycurrentButton        matlab.ui.control.RadioButton
        MagnetostaticButton            matlab.ui.control.RadioButton
        SymmetryButtonGroup            matlab.ui.container.ButtonGroup
        PlanesymmetricButton           matlab.ui.control.RadioButton
        AxissymmetricButton            matlab.ui.control.RadioButton
        CleanprojectButton             matlab.ui.control.Button
        EditParamsButton               matlab.ui.control.Button
        GeofileLabel                   matlab.ui.control.Label
        GeofileEditField               matlab.ui.control.EditField
        MeshfileEditFieldLabel         matlab.ui.control.Label
        MeshfileEditField              matlab.ui.control.EditField
        UpdateParamsButton             matlab.ui.control.Button
        StateLampLabel                 matlab.ui.control.Label
        problemSetupStateLamp          matlab.ui.control.Lamp
        SolverTab                      matlab.ui.container.Tab
        PreprocPanel                   matlab.ui.container.Panel
        ProcessButton                  matlab.ui.control.Button
        StateLampLabel_3               matlab.ui.control.Label
        solverStateLamp                matlab.ui.control.Lamp
        SelectgridtocalculatefieldonPanel  matlab.ui.container.Panel
        xminEditFieldLabel             matlab.ui.control.Label
        xminEditField                  matlab.ui.control.NumericEditField
        yminEditFieldLabel             matlab.ui.control.Label
        yminEditField                  matlab.ui.control.NumericEditField
        xmaxEditField_2Label           matlab.ui.control.Label
        xmaxEditField                  matlab.ui.control.NumericEditField
        ymaxEditFieldLabel             matlab.ui.control.Label
        ymaxEditField                  matlab.ui.control.NumericEditField
        EvaluatefieldButton            matlab.ui.control.Button
        NEditFieldLabel                matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        EnterpointstocalculatefieldalonglinesconnectingthesePanel  matlab.ui.container.Panel
        SelectedquantityDropDownLabel  matlab.ui.control.Label
        LineCalcDropdwn                matlab.ui.control.DropDown
        OutputtypeDropDownLabel        matlab.ui.control.Label
        OutputtypeDropDown             matlab.ui.control.DropDown
        SavefieldButton                matlab.ui.control.Button
        EditpointsButton               matlab.ui.control.Button
        NEditField_2Label              matlab.ui.control.Label
        NumPoints                      matlab.ui.control.NumericEditField
        EnergycalculationPanel         matlab.ui.container.Panel
        CalculateWButton               matlab.ui.control.Button
        RegionIDsEditFieldLabel        matlab.ui.control.Label
        RegionIDsEditField             matlab.ui.control.EditField
        ValueEditFieldLabel            matlab.ui.control.Label
        EnergyValueField               matlab.ui.control.NumericEditField
        LogTextAreaLabel               matlab.ui.control.Label
        LogTextArea                    matlab.ui.control.TextArea
        PlotTab                        matlab.ui.container.Tab
        PostprocPanel                  matlab.ui.container.Panel
        FieldTButtonGroup              matlab.ui.container.ButtonGroup
        EButton                        matlab.ui.control.RadioButton
        DButton                        matlab.ui.control.RadioButton
        VButton                        matlab.ui.control.RadioButton
        PlottypeButtonGroup            matlab.ui.container.ButtonGroup
        AbsButton                      matlab.ui.control.RadioButton
        AbsTriButton                   matlab.ui.control.RadioButton
        QuiverButton                   matlab.ui.control.RadioButton
        ContourButton                  matlab.ui.control.RadioButton
        SliceButton                    matlab.ui.control.RadioButton
        FieldlinesButton               matlab.ui.control.RadioButton
        OptionsPanel                   matlab.ui.container.Panel
        AxesOnCheckBox                 matlab.ui.control.CheckBox
        SavePlotCheckBox               matlab.ui.control.CheckBox
        FieldlinesOnCheckBox           matlab.ui.control.CheckBox
        contoursEditFieldLabel         matlab.ui.control.Label
        numContours                    matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel       matlab.ui.control.Label
        plotResolution                 matlab.ui.control.NumericEditField
        FormatDropDownLabel            matlab.ui.control.Label
        plotFormat                     matlab.ui.control.DropDown
        DisplayButton                  matlab.ui.control.Button
        CloseallfiguresButton          matlab.ui.control.Button
        SaveallfiguresButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        projPath = pwd; % Path to project
        projName = 'Default.proj'; % Initial project name
        
    end
    
    properties (Access = public)
        
        projectSelected = 1;
        
        files;
        
        settings = struct('problemClass', 'Electrostatic',...
            'symmetry', 'Plane symmetric',...
            'computation', 'Integration', ...
            'edgeType', 'Rectangular', ...
            'elementOrder', 1, 'xlims', [0,0], 'ylims', [0,0], 'Npoints', 25);
        
        plotSettings = struct('field', 'E', 'type', 'abs',...
            'axesOn', true, 'savePlot', false, ...
            'format', '-dpng', 'flinesOn', false,...
            'nCont', 24, 'res', 200, 'hideFig', ...
            false);
        
        
        meshInfo = struct('nNodes', 0, 'nElements', 0, 'nSys', 0);
        
        plotOptions = struct('estatic', {'E', 'D'}, ...
            'mstatic', {'B', 'H'});
        
        probTypeMap = containers.Map({'Electrostatic', 'Static current', 'Magnetostatic'}, [1,2,3]);
        probSymmMap = containers.Map({'Plane symmetric', 'Axis symmetric'}, [1,2]);
        
        regSet;
        meshPreviewSet = false;
        cmd;
        
        % Tab states :      1 - START
        %                   2 - INIT
        %                   3 - SETUP_OK
        %                   0 - DOWN
        DOWN = 0;
        START = 1;
        INIT = 2;
        SETUP_OK = 3;
        
        problemSetupState = 0;
        mesherState = 0;
        solverState = 0;
        
        problemSetupTabElementsVisible = false;
        mesherTabElementsVisible = false;
        solverTabElementsVisible = false;
        plotTabElementsVisible = false;
        
        RED = [1,0,0];
        YELLOW = [1,1,0];
        GREEN = [0,1,0];
        
        PS = '/';
        sys = 'lnx';
        
    end
    
    methods (Access = private)
        
        % Init fields :
        function initGuiItems(app)
            app.ProblemtypeButtonGroup.SelectedObject = 1;
        end
        
        % Changes the visibility of the tools if no proj selected
        
        function setProjectState(app, state)
            switch state
                case {'INIT'}
                    
                    app.projectSelected = true;
                    
                    app.resetRegionTables()
                    app.resetMeshInfoTables()
                    
                    if(~app.solverTabElementsVisible)
                        app.changeSolverTabState()
                    end
                    if(~app.plotTabElementsVisible)
                        app.changePlotTabState()
                    end
                    app.TabGroup.Visible = 'on';
                    
                    app.updateStates(app.START, app.START, app.START, app.START)
                    
                case {'SOLV'}
                    app.TabGroup.Visible = 'on';
                    if(~app.solverTabElementsVisible)
                        app.changeSolverTabState()
                    end
                    if(~app.plotTabElementsVisible)
                        app.changePlotTabState()
                    end
                    if(~app.solverTabElementsVisible)
                        app.changeSolverTabState()
                    end
                case {'PLOT'}
                    app.TabGroup.Visible = 'on';
                    if(~app.solverTabElementsVisible)
                        app.changeSolverTabState()
                    end
                    if(~app.plotTabElementsVisible)
                        app.changePlotTabState()
                    end
                    if(~app.solverTabElementsVisible)
                        app.changeSolverTabState()
                    end
                    if(~app.plotTabElementsVisible)
                        app.changePlotTabState()
                    end
                otherwise
                    error('Wrong state string')
            end
        end
        
        function saveState(app)
            
            projectSelected = app.projectSelected;
            files = app.files;
            settings = app.settings;
            plotSettings = app.plotSettings;
            meshInfo = app.meshInfo;
            plotOptions = app.plotOptions;
            regSet = app.regSet;
            cmd = app.cmd;
            PS = app.PS;
            sys = app.sys;
            mesherState = app.mesherState;
            problemSetupState = app.problemSetupState;
            solverState = app.solverState;
            
            save(app.files.projectFile, 'projectSelected', 'files', ...
                'settings', 'plotSettings', 'meshInfo', 'plotOptions',...
                'mesherState', 'problemSetupState', 'solverState', ...
                'regSet', 'cmd', 'PS', 'sys')
        end
        
        function loadState(app)
            
            load(app.files.projectFile, 'projectSelected', 'files', ...
                'settings', 'plotSettings', 'meshInfo', 'plotOptions',...
                'mesherState', 'problemSetupState', 'solverState', ...
                'regSet', 'cmd', 'PS', 'sys')
            
            app.projectSelected = projectSelected;
            %app.files = files;
            app.settings = settings;
            app.plotSettings = plotSettings;
            app.meshInfo = meshInfo;
            app.plotOptions = plotOptions;
            app.regSet = regSet;
            app.cmd = cmd;
            app.PS = PS;
            app.sys = sys;
            app.mesherState = mesherState;
            app.problemSetupState = problemSetupState;
            app.solverState = solverState;
        end
        
        function setState(app)
            app.ElementtypeButtonGroup.SelectedObject = ...
                app.ElementtypeButtonGroup.Buttons(app.settings.elementOrder);
            
            app.ProblemtypeButtonGroup.SelectedObject = ...
                app.ProblemtypeButtonGroup.Buttons(app.probTypeMap(app.settings.problemClass));
            
            app.SymmetryButtonGroup.SelectedObject = ...
                app.SymmetryButtonGroup.Buttons(app.probSymmMap(app.settings.symmetry));
            
            app.updatePlotOptions();
            
            app.updateLineCalcOptions();
            
        end
        
        function updateStates(app, probSetState, meshState, solvState, ~)
            
            if(probSetState ~= -1)
                app.problemSetupState = probSetState;
                app.updateProblemSetup()
            end
            
            if(meshState ~= -1)
                app.mesherState = meshState;
                app.updateMesher()
            end
            
            if(solvState ~= -1)
                app.solverState = solvState;
                app.updateSolver()
            end
            
            app.saveState()
            
        end
        
        %% State update functions %%
        function updateProblemSetup(app)
            switch app.problemSetupState
                case app.START
                    app.setProblemSetupSTART()
                case app.INIT
                    app.setProblemSetupINIT()
                case app.SETUP_OK
                    app.setProblemSetupSETUP_OK()
            end
        end
        
        function updateMesher(app)
            switch app.mesherState
                case app.START
                    app.setMesherSTART()
                case app.INIT
                    app.setMesherINIT()
                case app.SETUP_OK
                    app.setMesherSETUP_OK()
            end
        end
        
        function updateSolver(app)
            switch app.solverState
                case app.START
                    app.setSolverSTART()
                case app.INIT
                    app.setSolverINIT()
                case app.SETUP_OK
                    app.setSolverSETUP_OK()
            end
        end
        
        %% --- Problem setup state functions --- %%
        function setProblemSetupSTART(app)
            app.problemSetupStateLamp.Color = app.RED;
            app.resetRegionTables()
        end
        
        function setProblemSetupINIT(app)
            app.problemSetupStateLamp.Color = app.YELLOW;
            
        end
        
        function setProblemSetupSETUP_OK(app)
            app.problemSetupStateLamp.Color = app.GREEN;
            app.setProjectState('SOLV')
        end
        
        %% --- Mesher state functions --- %%
        function setMesherSTART(app)
            app.mesherStateLamp.Color = app.RED;
            app.resetMeshInfoTables()
            %app.resetCurrentMeshDisplay()
        end
        
        function setMesherINIT(app)
            app.mesherStateLamp.Color = app.YELLOW;
        end
        
        function setMesherSETUP_OK(app)
            app.mesherStateLamp.Color = app.GREEN;
        end
        %% --- Solver state functions --- %%
        function setSolverSTART(app)
            app.solverStateLamp.Color = app.RED;
            % update possible calculations
        end
        
        function setSolverINIT(app)
            app.solverStateLamp.Color = app.YELLOW;
            
        end
        
        function setSolverSETUP_OK(app)
            app.solverStateLamp.Color = app.GREEN;
            
        end
        %% ------------------------------ %%
        function resetRegionTables(app)
            app.IDTable.Data = {};
            app.NameTable.Data = {};
            app.TypeTable.Data = {};
            app.MatPropTable.Data = {};
            app.SourPropTable.Data = {};
        end
        
        function resetMeshInfoTables(app)
            app.MeshInfoTable.Data = {};
        end
        
        function resetCurrentMeshDisplay(app)
            cla(app.meshDisplayAxes)
        end
        
        function resetTabs(app)
            app.ProblemSetupTab.reset;
            app.MesherTab.reset;
            app.SolverTab.reset;
            app.SolverTab.reset;
        end
        
        function [state, isState] = getState(app, boolVar)
            isState = ~boolVar;
            if(isState)
                state = 'on';
            else
                state = 'off';
            end
        end
        
        function changeProblemSetupTabState(app)
            
            [state, isVisible] = app.getState(app.problemSetupTabElementsVisible);
            
            app.ProblemSetupPanel.Visible = state;
            
            app.problemSetupTabElementsVisible = isVisible;
            
        end
        
        function changeMesherTabState(app)
            
            [state, isVisible] = app.getState(app.mesherTabElementsVisible);
            
            app.MesherPanel.Visible = state;
            app.CurrentMeshPanel.Visible = state;
            
            app.mesherTabElementsVisible = isVisible;
            
        end
        
        function changeSolverTabState(app)
            
            [state, isVisible] = app.getState(app.solverTabElementsVisible);
            
            app.PreprocPanel.Visible = state;
            
            app.solverTabElementsVisible = isVisible;
            
        end
        
        function changePlotTabState(app)
            
            [state, isVisible] = app.getState(app.plotTabElementsVisible);
            
            app.PostprocPanel.Visible = state;
            
            app.plotTabElementsVisible = isVisible;
            
        end
        
        function updatePlotOptions(app)
            switch app.settings.problemClass
                case 'Electrostatic'
                    app.FieldTButtonGroup.Buttons(1).Text = 'E';
                    app.FieldTButtonGroup.Buttons(2).Text = 'D';
                    app.FieldTButtonGroup.Buttons(3).Text = 'V';
                case 'Static current'
                    app.FieldTButtonGroup.Buttons(1).Text = 'E';
                    app.FieldTButtonGroup.Buttons(2).Text = 'J';
                    app.FieldTButtonGroup.Buttons(3).Text = 'V';
                case 'Magnetostatic'
                    app.FieldTButtonGroup.Buttons(1).Text = 'B';
                    app.FieldTButtonGroup.Buttons(2).Text = 'H';
                    app.FieldTButtonGroup.Buttons(3).Text = 'A';
            end
        end
        
        function updateLineCalcOptions(app)
            switch app.settings.problemClass
                case 'Electrostatic'
                    app.LineCalcDropdwn.Items{1} = 'E';
                    app.LineCalcDropdwn.Items{2} = 'D';
                    app.LineCalcDropdwn.Items{3} = 'V';
                case 'Static current'
                    app.LineCalcDropdwn.Items{1} = 'E';
                    app.LineCalcDropdwn.Items{2} = 'J';
                    app.LineCalcDropdwn.Items{3} = 'V';   
                case 'Magnetostatic'
                    app.LineCalcDropdwn.Items{1} = 'B';
                    app.LineCalcDropdwn.Items{2} = 'H';
                    app.LineCalcDropdwn.Items{3} = 'A';
            end
        end
        
        function state = checkButtonState(app, button)
            value = button.Text;
            switch value
                case 'Electrostatic'
                    state = app.STATIC_E;
                case 'Static current'
                    state = app.STATIC_C;
                case 'Magnetostatic'
                    state = app.STATIC_M;
                case 'Plane symmetric'
                    state = app.PLANE_SYM;
                case 'Axis symmetric'
                    state = app.AXIS_SYM;
                case 'Integration'
                    state = app.COMP_INT;
                case 'Exact formula'
                    state = app.COMP_FORM;
                case 'Rectangular'
                    state = app.RECT;
                case 'Circular'
                    state = app.CIRC;
            end
        end
        
        function updateProblemSettings(app)
            
            settings = app.settings;
            save(app.files.setfile, 'settings')
            
        end
        
        function startPreprocessor(app)
            
            msh.prepFieldData(app.files, app.settings)
            
            log = ' - Setting up element matrices and calculating A';
            app.LogTextArea.Value = {app.LogTextArea.Value{:}, log};
            time = slv.calcA(app.files, app.settings);
            
            log = [' - Finished (Elapsed time : ', num2str(time) ' s)'];
            app.LogTextArea.Value = {app.LogTextArea.Value{:}, log};
            
            log = ' - Calculating field ...';
            app.LogTextArea.Value = {app.LogTextArea.Value{:}, log};
            time = app.evaluateField();
            
            log = [' - Finished (Elapsed time : ', num2str(time) ' s)'];
            app.LogTextArea.Value = {app.LogTextArea.Value{:}, log};
            
            app.setProjectState('PLOT')
            
        end
        
        function time = evaluateField(app)
            A = slv.evalA(app.files, app.settings);
            save(app.files.respth, 'A', '-append')
            
            % Calculations based on selected problem type
            switch app.settings.problemClass
                case 'Electrostatic'
                    time = slv.calcED(app.files, app.settings);
                case 'Static current'
                    time = slv.calcJ(app.files, app.settings);
                case 'Magnetostatic'
                    time = slv.calcBH(app.files, app.settings);
            end
        end
        
        function cleanProjectFolder(app)
            [status, message, messageid] = rmdir(app.files.pltpth, 's');
            [status, message, messageid] = rmdir(app.files.results, 's');
            delete(app.files.setfile);
            app.initComponents()
            app.updateStates(app.START, app.START, app.START, app.START)
        end
        
        function initRegionTables(app)
            app.regSet = ap.getRegions(app.files);
            
            app.IDTable.Data = {app.regSet.id}';
            app.NameTable.Data = {app.regSet.name}';
            app.SourPropTable.Data = {app.regSet.srcProp}';
            app.MatPropTable.Data = {app.regSet.matProp}';
            app.TypeTable.Data = {app.regSet.spec}';
            
        end
        
        function resetButtons(app)
            app.ElementtypeButtonGroup.SelectedObject = ...
                app.ElementtypeButtonGroup.Buttons(1);
            
            app.ProblemtypeButtonGroup.SelectedObject = ...
                app.ProblemtypeButtonGroup.Buttons(1);
            
            app.SymmetryButtonGroup.SelectedObject = ...
                app.SymmetryButtonGroup.Buttons(1);
        end
        
    end
    
    methods (Access = public)
        
        function initData(app, filePath, fileName)
            
            app.files = io.generateFiles(filePath, fileName, app.PS);
            app.cmd = gmsh.generateCommands(app.files, app.sys, ...
                app.settings.elementOrder, app.PS);
            
        end
        
        function initComponents(app)
            
            addpath('functions')
            
            if(ispc)
                app.sys = 'win';
                app.PS = '\';
            end
            
            % Project selected state :
            app.TabGroup.Visible = 'off';
            
            % UIAxes :
            title(app.meshDisplayAxes, '')
            ylabel(app.meshDisplayAxes, '')
            xlabel(app.meshDisplayAxes, '')
            
            % Project name init
            app.setProjectTitle()
            
            %app.initRegionTablesTables()
            app.meshPreviewSet = false;
            
            % Reset buttons :
            app.resetCurrentMeshDisplay()
            app.resetMeshInfoTables()
            app.resetRegionTables()
            app.resetButtons()
            
            %% Lamps intializer :
            % MesherLamp
            app.problemSetupStateLamp.Color = app.RED;
            app.mesherStateLamp.Color = app.RED;
            app.solverStateLamp.Color = app.RED;
            
            % Log window : 
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Value = {'FEMLab log : ', ''};
            
        end
        
        % Sets the element type image
        function setElementTypeImg(app, i)
            %img = imread(['./AppFiles/figures/', num2str(i), 'o_triangle.png']);
            %imshow(img, 'parent', app.ElemTypeAxes)
        end
        
        % Sets project title
        function setProjectTitle(app)
            app.PreprocPanel.Title = app.projName;
            app.MesherPanel.Title = app.projName;
            app.PostprocPanel.Title = app.projName;
            app.ProblemSetupPanel.Title = app.projName;
            
            if(isempty(app.files))
                app.GeofileEditField.Value = 'default.geo';
                app.MeshfileEditField.Value = 'default.msh';
            else
                app.GeofileEditField.Value = app.files.geo;
                app.MeshfileEditField.Value = app.files.mesh;
            end
        end
        
        function index = mapProblemType(app)
            
        end
        
        function setMeshPreview(app)
            gfx.display(app.files, app.settings, 'MSH', 'saveplot', true, ...
                'hideFig', true)
            img = imread([app.files.pltpthMesh, '.png']);
            imshow(img, 'parent', app.meshDisplayAxes)
        end
        
        function setPlotType(app, buttonStr)
            switch buttonStr
                case 'Abs'
                    app.plotSettings.type = 'abs';
                case 'Abs Tri'
                    app.plotSettings.type = 'abstri';
                case 'Quiver'
                    app.plotSettings.type = 'quiv';
                case 'Contour'
                    app.plotSettings.type = 'cont';
                case 'Slice'
                    app.plotSettings.type = 'slice';
                case 'Field lines'
                    app.plotSettings.type = 'flines';
            end
        end
        
        function setFormatIdn(app, value)
            switch value
                case 'PNG'
                    app.plotSettings.format = '-dpng';
                case 'EPS'
                    app.plotSettings.format = '-depsc';
                case 'JPG'
                    app.plotSettings.format = '-djpg';
            end
        end
        
        function projectSetup(app, state)
            
            if(strcmp(state, 'NEW'))
                
                prompt = 'Project name :';
                dlgtitle = 'Enter project name :';
                definput = {'Problem'};
                dims = [1 60];
                
                inputCell = inputdlg(prompt,dlgtitle,dims,definput);
                
                app.projName = inputCell{1};
                
                selectFolderString = 'Select location for project';
                
                projectPath = uigetdir(pwd, selectFolderString);
                app.projPath = [projectPath, app.PS, app.projName];
                
                mkdir(app.projPath)
                
            elseif(strcmp(state, 'OPEN'))
                
                selectFolderString = 'Select project file';
                
                [app.projName, app.projPath] = uigetfile(pwd, '*.mat', selectFolderString);
                app.projName = app.projName(1:end-4);
                
            end
            
        end
        
        function updateMeshInfoTable(app)
            app.meshInfo = msh.updateMeshInfo(app.files, app.meshInfo);
            app.setValuesMeshInfoTable()
        end
        
        function setValuesMeshInfoTable(app)
            data = {app.meshInfo.nNodes, app.meshInfo.nElements, app.meshInfo.nSys};
            app.MeshInfoTable.Data = data;
        end
        
        function elemOrder = getElementOrder(app, text)
            switch text
                case 'Triangle 1st Order'
                    elemOrder = 1;
                case 'Triangle 2nd Order'
                    elemOrder = 2;
                case 'Triangle 3rd Order'
                    elemOrder = 3;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
            app.projectSetup('OPEN')
            
            app.initData(app.projPath, app.projName)
            io.initConfigParams(app.files);
            
            listOfVariables = who('-file', app.files.respth);
            dataExists = ismember('regSet', listOfVariables);
            
            app.setProjectTitle()
            
            if(~dataExists)
                app.setProjectState('INIT')
            else
                app.loadState()
                app.setState()
                app.setValuesMeshInfoTable()
                app.initRegionTables()
                app.setMeshPreview()
                
                if(app.solverState == app.SETUP_OK)
                    app.setProjectState('PLOT')
                    app.setSolverSETUP_OK()
                    app.setProblemSetupSETUP_OK()
                    app.setMesherSETUP_OK()
                elseif(app.problemSetupState == app.SETUP_OK && app.mesherState == app.SETUP_OK)
                    app.setProjectState('SOLV')
                    app.setProblemSetupSETUP_OK()
                    app.setMesherSETUP_OK()
                else
                    app.setProjectState('INIT')
                end
            end
            
        end

        % Selection changed function: ProblemtypeButtonGroup
        function ProblemtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemtypeButtonGroup.SelectedObject;
            app.settings.problemClass = selectedButton.Text;
            app.updateProblemSettings();
            app.updatePlotOptions();
            app.updateLineCalcOptions();
            app.updateStates(-1, -1, app.START, app.START)
        end

        % Selection changed function: SymmetryButtonGroup
        function SymmetryButtonGroupSelectionChanged(app, event)
            selectedButton = app.SymmetryButtonGroup.SelectedObject;
            app.settings.symmetry = selectedButton.Text;
            app.updateProblemSettings();
            app.updateStates(-1, -1, app.START, app.START)
        end

        % Callback function
        function CalculationtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationtypeButtonGroup.SelectedObject;
            app.settings.computation = selectedButton.Text;
            app.updateProblemSettings();
            app.updateStates(-1, -1, app.INIT, -1)
        end

        % Callback function
        function EdgetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.EdgetypeButtonGroup.SelectedObject;
            app.settings.edgeType = selectedButton.Text;
            app.updateProblemSettings();
            app.updateStates(-1, -1, app.INIT, -1)
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.startPreprocessor()
            app.updateStates(-1, -1, app.SETUP_OK, -1)
        end

        % Button pushed function: CleanprojectButton
        function CleanprojectButtonPushed(app, event)
            app.cleanProjectFolder()
            app.updateStates(app.START, app.START, -1, -1)
        end

        % Callback function
        function ComputeEButtonPushed(app, event)
            app.changeComputeLampState(app.EcomputedLamp, app.EcomputedLabel, 'busy')
            slv.calcE(app.files, app.settings)
            app.changeComputeLampState(app.EcomputedLamp, app.EcomputedLabel, 'ready')
        end

        % Callback function
        function ComputeJButtonPushed(app, event)
            app.changeComputeLampState(app.JcomputedLamp, app.JcomputedLampLabel, 'busy')
            slv.calcJ(app.files, app.settings)
            app.changeComputeLampState(app.JcomputedLamp, app.EcomputedLampLabel, 'ready')
        end

        % Callback function
        function ComputeBButtonPushed(app, event)
            app.changeComputeLampState(app.BcomputedLamp, app.BcomputedLampLabel, 'busy')
            slv.calcB(app.files, app.settings)
            app.changeComputeLampState(app.BcomputedLamp, app.BcomputedLampLabel, 'ready')
        end

        % Callback function
        function ComputeAButtonPushed(app, event)
            app.changeComputeLampState(app.AcomputedLamp, app.AcomputedLampLabel, 'busy')
            slv.evalA(app.files)
            app.changeComputeLampState(app.AcomputedLamp, app.AcomputedLampLabel, 'ready')
        end

        % Selection changed function: FieldTButtonGroup
        function FieldTButtonGroupSelectionChanged(app, event)
            selectedButton = app.FieldTButtonGroup.SelectedObject;
            app.plotSettings.field = selectedButton.Text;
        end

        % Selection changed function: PlottypeButtonGroup
        function PlottypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottypeButtonGroup.SelectedObject;
            app.setPlotType(selectedButton.Text);
        end

        % Value changed function: AxesOnCheckBox
        function AxesOnCheckBoxValueChanged(app, event)
            value = app.AxesOnCheckBox.Value;
            app.plotSettings.axesOn = value;
        end

        % Value changed function: SavePlotCheckBox
        function SavePlotCheckBoxValueChanged(app, event)
            value = app.SavePlotCheckBox.Value;
            app.plotSettings.savePlot = value;
        end

        % Value changed function: FieldlinesOnCheckBox
        function FieldlinesOnCheckBoxValueChanged(app, event)
            value = app.FieldlinesOnCheckBox.Value;
            app.plotSettings.flinesOn = value;
        end

        % Value changed function: numContours
        function numContoursValueChanged(app, event)
            value = app.numContours.Value;
            app.plotSettings.nCont = value;
        end

        % Value changed function: plotResolution
        function plotResolutionValueChanged(app, event)
            value = app.plotResolution.Value;
            app.plotSettings.res = value;
        end

        % Value changed function: plotFormat
        function plotFormatValueChanged(app, event)
            value = app.plotFormat.Value;
            app.setFormatIdn(value);
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            
            selectedButton = app.FieldTButtonGroup.SelectedObject;
            app.plotSettings.field = selectedButton.Text;
            
            gfx.display(app.files, app.settings, app.plotSettings.field, 'type', app.plotSettings.type,...
                'savePlot', app.plotSettings.savePlot, 'fieldLinesOn', app.plotSettings.flinesOn, ...
                'nCont', app.plotSettings.nCont, 'res', app.plotSettings.res, 'format', app.plotSettings.format)
        end

        % Callback function
        function RegionParamsTableCellEdit(app, event)
            app.regParams = app.RegionParamsTable.Data;
        end

        % Cell edit callback: NameTable
        function NameTableCellEdit(app, event)
            
            app.regSet = app.regSet.setRegRegs(app.NameTable.Data', app.regSet.regs.values);
            app.regSet = app.regSet.setRegDef(app.NameTable.Data', app.regSet.def.values);
            app.regSet = app.regSet.setRegMap(app.regSet.rMap.keys, app.NameTable.Data');
            
            ap.setRegions(app.files, app.regSet)
        end

        % Callback function
        function ParamTableCellEdit(app, event)
            
            app.regSet = app.regSet.setRegRegs(app.regSet.regs.keys, app.ParamTable.Data');
            ap.setRegions(app.files, app.regSet)
        end

        % Cell edit callback: TypeTable
        function TypeTableCellEdit(app, event)
            %app.updateRegParams()
        end

        % Button pushed function: EditParamsButton
        function EditParamsButtonPushed(app, event)
            open(app.files.regfile);
            app.updateStates(app.INIT, -1, app.START, app.START)
        end

        % Button pushed function: UpdateParamsButton
        function UpdateParamsButtonPushed(app, event)
            io.parseRegions(app.files);
            msh.regionsSetup(app.files)
            app.initRegionTables()
            app.updateStates(app.SETUP_OK, -1, -1, -1)
        end

        % Button pushed function: CloseallfiguresButton
        function CloseallfiguresButtonPushed(app, event)
            close all;
        end

        % Button pushed function: EditgeometryButton
        function EditgeometryButtonPushed(app, event)
            [status, ~] = gmsh.run(app.cmd.editGeo);
            if(status ~= 0)
                error('Something went wrong')
            end
            app.updateStates(app.START, app.START, app.START, app.START)
        end

        % Callback function
        function MeshgeometryButtonPushed(app, event)
            [status, ~] = gmsh.run(app.cmd.meshAndSave);
            [status, ~] = gmsh.run(app.cmd.refineMesh);
            app.updateStates(-1, app.INIT, app.START, app.START)
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            
            app.initComponents()
            
            % Setup folder structure and set path
            app.projectSetup('NEW')
            
            % Initialize project
            app.initData(app.projPath, app.projName)
            app.setProjectTitle()
            
            % Make mesh and save
            gmsh.run(app.cmd.editGeo)
            
            app.setProjectState('INIT')
        end

        % Callback function
        function RefinemeshButtonPushed(app, event)
            [status, ~] = gmsh.run(app.cmd.refineMesh);
        end

        % Button pushed function: RunsetupButton
        function RunsetupButtonPushed(app, event)
            app.files = io.readMeshFile(app.files, app.settings.elementOrder, app.LogTextArea);
            io.initConfigParams(app.files);
            io.parseRegions(app.files);
            msh.regionsSetup(app.files)
            app.updateMeshInfoTable()
            app.setMeshPreview()
            
            app.updateStates(-1, app.SETUP_OK, -1, -1)
        end

        % Selection changed function: ElementtypeButtonGroup
        function ElementtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ElementtypeButtonGroup.SelectedObject;
            app.settings.elementOrder = app.getElementOrder(selectedButton.Text);
            app.cmd = gmsh.generateCommands(app.files, app.sys, ...
                app.settings.elementOrder, app.PS);
            app.updateStates(app.START, app.START, app.START, app.START)
        end

        % Button pushed function: EditpointsButton
        function EditpointsButtonPushed(app, event)
            open(app.files.linePoints);
        end

        % Button pushed function: SavefieldButton
        function SavefieldButtonPushed(app, event)
            try
                xy = io.readLinePointsFile(app.files.linePoints);
            catch
                disp(['Wrong input format line points : ', app.files.linePoints])
            end
            
            N = app.NumPoints.Value;
            selectedQuantity = app.LineCalcDropdwn.Value;
            
            [x,y] = msh.getLineCoordinates(xy, N);
            
            switch(selectedQuantity)
                case {'A','V'}
                    Q = slv.evalA(app.files, app.settings, x, y);
                case 'B'
                    [Q, Qx, Qy] = slv.evalB(app.files, app.settings, x, y);
                case 'H'
                    [Q, Qx, Qy] = slv.evalH(app.files, app.settings, x, y);
                case 'E'
                    [Q, Qx, Qy] = slv.evalE(app.files, app.settings, x, y);
                case 'D'
                    [Q, Qx, Qy] = slv.evalD(app.files, app.settings, x, y);
            end
            
            dataPath = [app.files.results selectedQuantity '_line_values.mat'];
            disp(['Saved data to : ' dataPath])
            if(~ismember('A', {'V','A'}))
                save(dataPath, 'Q', 'Qx', 'Qy')
            else
                save(dataPath, 'Q')
            end
        end

        % Button pushed function: EvaluatefieldButton
        function EvaluatefieldButtonPushed(app, event)
            
            app.settings.xlims = [app.xminEditField.Value, app.xmaxEditField.Value];
            app.settings.ylims = [app.yminEditField.Value, app.ymaxEditField.Value];
            app.settings.Npoints = app.NEditField.Value;
            
            msh.prepFieldData(app.files, app.settings)
            app.evaluateField()
            
        end

        % Button pushed function: SaveallfiguresButton
        function SaveallfiguresButtonPushed(app, event)
            gfx.saveFigures(app.files.pltpth, app.plotFormat.Value, app.plotResolution.Value)
        end

        % Button pushed function: CalculateWButton
        function CalculateWButtonPushed(app, event)
            regions = eval(app.RegionIDsEditField.Value);
            W = slv.calcW(app.files, app.settings, regions);
            app.EnergyValueField.Value = W;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FEMLabUIFigure = uifigure('Visible', 'off');
            app.FEMLabUIFigure.Name = 'FEMLab';
            app.FileMenu = uimenu(app.FEMLabUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'O';
            app.OpenMenu.Text = 'Open';
            app.SavesettingsMenu = uimenu(app.FileMenu);
            app.SavesettingsMenu.Accelerator = 'S';
            app.SavesettingsMenu.Text = 'Save settings';
            app.HelpMenu = uimenu(app.FEMLabUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.FEMLabUIFigure);
            app.MesherTab = uitab(app.TabGroup);
            app.MesherTab.Title = 'Mesher';
            app.CurrentMeshPanel = uipanel(app.MesherTab);
            app.CurrentMeshPanel.Title = 'Current Mesh ';
            app.meshDisplayAxes = uiaxes(app.CurrentMeshPanel);
            app.meshDisplayAxes.XTick = [];
            app.meshDisplayAxes.YTick = [];
            app.MesherPanel = uipanel(app.MesherTab);
            app.MesherPanel.Title = 'Default.proj';
            app.ElementtypeButtonGroup = uibuttongroup(app.MesherPanel);
            app.ElementtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ElementtypeButtonGroupSelectionChanged, true);
            app.ElementtypeButtonGroup.Title = 'Element type :';
            app.Triangle1stOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle1stOrderButton.Text = 'Triangle 1st Order';
            app.Triangle1stOrderButton.Value = true;
            app.Triangle2ndOrderButton = uiradiobutton(app.ElementtypeButtonGroup);
            app.Triangle2ndOrderButton.Text = 'Triangle 2nd Order';
            app.RunsetupButton = uibutton(app.MesherPanel, 'push');
            app.RunsetupButton.ButtonPushedFcn = createCallbackFcn(app, @RunsetupButtonPushed, true);
            app.RunsetupButton.Text = 'Run setup';
            app.EditgeometryButton = uibutton(app.MesherPanel, 'push');
            app.EditgeometryButton.ButtonPushedFcn = createCallbackFcn(app, @EditgeometryButtonPushed, true);
            app.EditgeometryButton.Text = 'Edit geometry';
            app.MeshInfoTable = uitable(app.MesherPanel);
            app.MeshInfoTable.ColumnName = {'# Nodes'; '# Elements'; '# Unknowns'};
            app.MeshInfoTable.RowName = {};
            app.StateLampLabel_2 = uilabel(app.MesherPanel);
            app.StateLampLabel_2.Text = 'State';
            app.mesherStateLamp = uilamp(app.MesherPanel);
            app.ProblemSetupTab = uitab(app.TabGroup);
            app.ProblemSetupTab.Title = 'Problem Setup';
            app.ProblemSetupPanel = uipanel(app.ProblemSetupTab);
            app.ProblemSetupPanel.Title = 'Default.proj';
            app.RegionsPanel = uipanel(app.ProblemSetupPanel);
            app.RegionsPanel.Title = 'Regions : ';
            app.IDTable = uitable(app.RegionsPanel);
            app.IDTable.ColumnName = {'ID'};
            app.IDTable.RowName = {};
            app.NameTable = uitable(app.RegionsPanel);
            app.NameTable.ColumnName = {'Name'};
            app.NameTable.RowName = {};
            app.NameTable.CellEditCallback = createCallbackFcn(app, @NameTableCellEdit, true);
            app.TypeTable = uitable(app.RegionsPanel);
            app.TypeTable.ColumnName = {'Type'};
            app.TypeTable.RowName = {};
            app.TypeTable.CellEditCallback = createCallbackFcn(app, @TypeTableCellEdit, true);
            app.MatPropTable = uitable(app.RegionsPanel);
            app.MatPropTable.ColumnName = {'Material Prop'};
            app.MatPropTable.RowName = {};
            app.SourPropTable = uitable(app.RegionsPanel);
            app.SourPropTable.ColumnName = {'Source Prop'};
            app.SourPropTable.RowName = {};
            app.ProblemtypeButtonGroup = uibuttongroup(app.ProblemSetupPanel);
            app.ProblemtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemtypeButtonGroupSelectionChanged, true);
            app.ProblemtypeButtonGroup.Title = 'Problem type : ';
            app.ElectrostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.ElectrostaticButton.Text = 'Electrostatic';
            app.ElectrostaticButton.Value = true;
            app.StationarycurrentButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.StationarycurrentButton.Text = 'Stationary current';
            app.MagnetostaticButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.MagnetostaticButton.Text = 'Magnetostatic';
            app.SymmetryButtonGroup = uibuttongroup(app.ProblemSetupPanel);
            app.SymmetryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SymmetryButtonGroupSelectionChanged, true);
            app.SymmetryButtonGroup.Title = 'Symmetry :';
            app.PlanesymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.PlanesymmetricButton.Text = 'Plane symmetric';
            app.PlanesymmetricButton.Value = true;
            app.AxissymmetricButton = uiradiobutton(app.SymmetryButtonGroup);
            app.AxissymmetricButton.Text = 'Axis symmetric';
            app.CleanprojectButton = uibutton(app.ProblemSetupPanel, 'push');
            app.CleanprojectButton.ButtonPushedFcn = createCallbackFcn(app, @CleanprojectButtonPushed, true);
            app.CleanprojectButton.Text = 'Clean project';
            app.EditParamsButton = uibutton(app.ProblemSetupPanel, 'push');
            app.EditParamsButton.ButtonPushedFcn = createCallbackFcn(app, @EditParamsButtonPushed, true);
            app.EditParamsButton.Text = 'Edit params';
            app.GeofileLabel = uilabel(app.ProblemSetupPanel);
            app.GeofileLabel.Text = 'Geo file : ';
            app.GeofileEditField = uieditfield(app.ProblemSetupPanel, 'text');
            app.GeofileEditField.Value = 'default.geo';
            app.MeshfileEditFieldLabel = uilabel(app.ProblemSetupPanel);
            app.MeshfileEditFieldLabel.Text = 'Mesh file : ';
            app.MeshfileEditField = uieditfield(app.ProblemSetupPanel, 'text');
            app.MeshfileEditField.Value = 'default.msh';
            app.UpdateParamsButton = uibutton(app.ProblemSetupPanel, 'push');
            app.UpdateParamsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParamsButtonPushed, true);
            app.UpdateParamsButton.Text = 'Update params';
            app.StateLampLabel = uilabel(app.ProblemSetupPanel);
            app.StateLampLabel.Text = 'State';
            app.problemSetupStateLamp = uilamp(app.ProblemSetupPanel);
            app.SolverTab = uitab(app.TabGroup);
            app.SolverTab.Title = 'Solver';
            app.PreprocPanel = uipanel(app.SolverTab);
            app.PreprocPanel.Title = 'Default.proj';
            app.ProcessButton = uibutton(app.PreprocPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.StateLampLabel_3 = uilabel(app.PreprocPanel);
            app.StateLampLabel_3.Text = 'State';
            app.solverStateLamp = uilamp(app.PreprocPanel);
            app.SelectgridtocalculatefieldonPanel = uipanel(app.PreprocPanel);
            app.SelectgridtocalculatefieldonPanel.Title = 'Select grid to calculate field on';
            app.xminEditFieldLabel = uilabel(app.SelectgridtocalculatefieldonPanel);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.SelectgridtocalculatefieldonPanel, 'numeric');
            app.yminEditFieldLabel = uilabel(app.SelectgridtocalculatefieldonPanel);
            app.yminEditFieldLabel.Text = 'ymin';
            app.yminEditField = uieditfield(app.SelectgridtocalculatefieldonPanel, 'numeric');
            app.xmaxEditField_2Label = uilabel(app.SelectgridtocalculatefieldonPanel);
            app.xmaxEditField_2Label.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.SelectgridtocalculatefieldonPanel, 'numeric');
            app.ymaxEditFieldLabel = uilabel(app.SelectgridtocalculatefieldonPanel);
            app.ymaxEditFieldLabel.Text = 'ymax';
            app.ymaxEditField = uieditfield(app.SelectgridtocalculatefieldonPanel, 'numeric');
            app.EvaluatefieldButton = uibutton(app.SelectgridtocalculatefieldonPanel, 'push');
            app.EvaluatefieldButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluatefieldButtonPushed, true);
            app.EvaluatefieldButton.Text = 'Evaluate field';
            app.NEditFieldLabel = uilabel(app.SelectgridtocalculatefieldonPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.SelectgridtocalculatefieldonPanel, 'numeric');
            app.EnterpointstocalculatefieldalonglinesconnectingthesePanel = uipanel(app.PreprocPanel);
            app.EnterpointstocalculatefieldalonglinesconnectingthesePanel.Title = 'Enter points to calculate field along lines connecting these';
            app.SelectedquantityDropDownLabel = uilabel(app.EnterpointstocalculatefieldalonglinesconnectingthesePanel);
            app.SelectedquantityDropDownLabel.Text = 'Selected quantity';
            app.LineCalcDropdwn = uidropdown(app.EnterpointstocalculatefieldalonglinesconnectingthesePanel);
            app.LineCalcDropdwn.Items = {'A', 'E', 'D'};
            app.LineCalcDropdwn.Value = 'A';
            app.OutputtypeDropDownLabel = uilabel(app.EnterpointstocalculatefieldalonglinesconnectingthesePanel);
            app.OutputtypeDropDownLabel.Text = 'Output type : ';
            app.OutputtypeDropDown = uidropdown(app.EnterpointstocalculatefieldalonglinesconnectingthesePanel);
            app.OutputtypeDropDown.Items = {'mat', 'txt'};
            app.OutputtypeDropDown.Value = 'mat';
            app.SavefieldButton = uibutton(app.EnterpointstocalculatefieldalonglinesconnectingthesePanel, 'push');
            app.SavefieldButton.ButtonPushedFcn = createCallbackFcn(app, @SavefieldButtonPushed, true);
            app.SavefieldButton.Text = 'Save field';
            app.EditpointsButton = uibutton(app.EnterpointstocalculatefieldalonglinesconnectingthesePanel, 'push');
            app.EditpointsButton.ButtonPushedFcn = createCallbackFcn(app, @EditpointsButtonPushed, true);
            app.EditpointsButton.Text = 'Edit points';
            app.NEditField_2Label = uilabel(app.EnterpointstocalculatefieldalonglinesconnectingthesePanel);
            app.NEditField_2Label.Text = 'N';
            app.NumPoints = uieditfield(app.EnterpointstocalculatefieldalonglinesconnectingthesePanel, 'numeric');
            app.NumPoints.Value = 10;
            app.EnergycalculationPanel = uipanel(app.PreprocPanel);
            app.EnergycalculationPanel.Title = 'Energy calculation';
            app.CalculateWButton = uibutton(app.EnergycalculationPanel, 'push');
            app.CalculateWButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateWButtonPushed, true);
            app.CalculateWButton.Text = 'Calculate';
            app.ValueEditFieldLabel = uilabel(app.EnergycalculationPanel);
            app.ValueEditFieldLabel.Text = 'Value : ';
            app.EnergyValueField = uieditfield(app.EnergycalculationPanel, 'numeric');
            app.RegionIDsEditFieldLabel = uilabel(app.EnergycalculationPanel);
            app.RegionIDsEditFieldLabel.Text = 'Region IDs';
            app.RegionIDsEditField = uieditfield(app.EnergycalculationPanel, 'text');
            app.RegionIDsEditField.Value = '[1,2,3]';
            app.LogTextAreaLabel = uilabel(app.PreprocPanel);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.PreprocPanel);
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Value = {'FEMLab log : '};
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PostprocPanel = uipanel(app.PlotTab);
            app.PostprocPanel.Title = 'Plot';
            app.FieldTButtonGroup = uibuttongroup(app.PostprocPanel);
            app.FieldTButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FieldTButtonGroupSelectionChanged, true);
            app.FieldTButtonGroup.Title = 'Plot quantity  : ';
            app.EButton = uiradiobutton(app.FieldTButtonGroup);
            app.EButton.Text = 'E';
            app.EButton.Value = true;
            app.DButton = uiradiobutton(app.FieldTButtonGroup);
            app.DButton.Text = 'D';
            app.VButton = uiradiobutton(app.FieldTButtonGroup);
            app.VButton.Text = 'V';
            app.PlottypeButtonGroup = uibuttongroup(app.PostprocPanel);
            app.PlottypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottypeButtonGroupSelectionChanged, true);
            app.PlottypeButtonGroup.Title = 'Plot type  : ';
            app.AbsButton = uiradiobutton(app.PlottypeButtonGroup);
            app.AbsButton.Text = 'Abs';
            app.AbsButton.Value = true;
            app.AbsTriButton = uiradiobutton(app.PlottypeButtonGroup);
            app.AbsTriButton.Text = 'Abs Tri';
            app.QuiverButton = uiradiobutton(app.PlottypeButtonGroup);
            app.QuiverButton.Text = 'Quiver';
            app.ContourButton = uiradiobutton(app.PlottypeButtonGroup);
            app.ContourButton.Text = 'Contour';
            app.SliceButton = uiradiobutton(app.PlottypeButtonGroup);
            app.SliceButton.Text = 'Slice';
            app.FieldlinesButton = uiradiobutton(app.PlottypeButtonGroup);
            app.FieldlinesButton.Text = 'Field lines';
            app.OptionsPanel = uipanel(app.PostprocPanel);
            app.OptionsPanel.Title = 'Options : ';
            app.AxesOnCheckBox = uicheckbox(app.OptionsPanel);
            app.AxesOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @AxesOnCheckBoxValueChanged, true);
            app.AxesOnCheckBox.Text = 'Axes On';
            app.AxesOnCheckBox.Value = true;
            app.SavePlotCheckBox = uicheckbox(app.OptionsPanel);
            app.SavePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavePlotCheckBoxValueChanged, true);
            app.SavePlotCheckBox.Text = 'Save Plot';
            app.FieldlinesOnCheckBox = uicheckbox(app.OptionsPanel);
            app.FieldlinesOnCheckBox.ValueChangedFcn = createCallbackFcn(app, @FieldlinesOnCheckBoxValueChanged, true);
            app.FieldlinesOnCheckBox.Text = 'Field lines On';
            app.contoursEditFieldLabel = uilabel(app.OptionsPanel);
            app.contoursEditFieldLabel.Text = '# contours';
            app.numContours = uieditfield(app.OptionsPanel, 'numeric');
            app.numContours.Limits = [0 100];
            app.numContours.ValueChangedFcn = createCallbackFcn(app, @numContoursValueChanged, true);
            app.numContours.Value = 24;
            app.ResolutionEditFieldLabel = uilabel(app.OptionsPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.plotResolution = uieditfield(app.OptionsPanel, 'numeric');
            app.plotResolution.Limits = [50 400];
            app.plotResolution.ValueChangedFcn = createCallbackFcn(app, @plotResolutionValueChanged, true);
            app.plotResolution.Value = 200;
            app.FormatDropDownLabel = uilabel(app.OptionsPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.plotFormat = uidropdown(app.OptionsPanel);
            app.plotFormat.Items = {'png', 'pdf', 'jpg', 'eps'};
            app.plotFormat.ValueChangedFcn = createCallbackFcn(app, @plotFormatValueChanged, true);
            app.plotFormat.Value = 'png';
            app.DisplayButton = uibutton(app.PostprocPanel, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
            app.CloseallfiguresButton = uibutton(app.PostprocPanel, 'push');
            app.CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfiguresButtonPushed, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            app.SaveallfiguresButton = uibutton(app.PostprocPanel, 'push');
            app.SaveallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallfiguresButtonPushed, true);
            app.SaveallfiguresButton.Text = 'Save all figures';
            % Show the figure after all components are created
            app.FEMLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEMLabApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FEMLabUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FEMLabUIFigure)
        end
    end
end